classdef myapp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Slider       matlab.ui.control.Slider
        Label_2      matlab.ui.control.Label
        EditField    matlab.ui.control.NumericEditField
        Label        matlab.ui.control.Label
        ButtonGroup  matlab.ui.container.ButtonGroup
        HPFButton    matlab.ui.control.RadioButton
        LPFButton    matlab.ui.control.RadioButton
        Button_4     matlab.ui.control.Button
        Button_3     matlab.ui.control.Button
        Button       matlab.ui.control.Button
        UIAxes_2     matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file,path] = uigetfile;
            [y,fs] = audioread(fullfile(path,file)); % ファイルの読み込み
            t = 0 : 1/fs : numel(y)/fs - 1/fs; % 時間ベクトルの定義
            plot(app.UIAxes,t,y)                   % 時間軸でのデータ表示
            [p,f] = pspectrum(y,fs);    % パワースペクトルの計算
            plot(app.UIAxes_2,f,10*log10(p))         % 周波数軸でのデータ表示
            sound(y,fs)                 % 音の再生
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, '時間軸波形')
            xlabel(app.UIAxes, '時間[sec]')
            ylabel(app.UIAxes, '振幅')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, '周波数波形')
            xlabel(app.UIAxes_2, '周波数[Hz]')
            ylabel(app.UIAxes_2, 'パワースペクトル')
            zlabel(app.UIAxes_2, 'Z')
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'ファイルの読み込み';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.Text = 'ファイルの保存';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.Text = 'フィルターの適用';
            app.ButtonGroup = uibuttongroup(app.GridLayout);
            app.ButtonGroup.Title = 'Button Group';
            app.LPFButton = uiradiobutton(app.ButtonGroup);
            app.LPFButton.Text = 'LPF';
            app.LPFButton.Value = true;
            app.HPFButton = uiradiobutton(app.ButtonGroup);
            app.HPFButton.Text = 'HPF';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'パスバンド';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.Value = 1000;
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'ゲイン';
            app.Slider = uislider(app.GridLayout);
            app.Slider.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myapp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end